classdef medium < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Wave1Panel                   matlab.ui.container.Panel
        FreqLabel                    matlab.ui.control.Label
        AmpLabel                     matlab.ui.control.Label
        FrequencySlider              matlab.ui.control.Slider
        FrequencySliderLabel         matlab.ui.control.Label
        AmplitudeSlider              matlab.ui.control.Slider
        AmplitudeSliderLabel         matlab.ui.control.Label
        WaveMedium                   matlab.ui.container.Panel
        RefractionIndexSliderLabel   matlab.ui.control.Label
        ExtinctioncoefficientSlider  matlab.ui.control.Slider
        RefractionIndexSlider        matlab.ui.control.Slider
        ExtinctioncoefficientSliderLabel  matlab.ui.control.Label
        DisplayPanel                 matlab.ui.container.Panel
        DurationsecEditField         matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel    matlab.ui.control.Label
        SamplingRateEditField        matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel   matlab.ui.control.Label
        ResetWavesButton             matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_Wave1                 matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updateplot(app)
            % Sampling rate and duration
            samplePerCycle = app.SamplingRateEditField.Value;
            duration = app.DurationsecEditField.Value;
        
            % Wave1
            fc1 = round(app.FrequencySlider.Value,2);  
            amp1 = round(app.AmplitudeSlider.Value,2); 
            fs1 = fc1 * samplePerCycle;
            
            t1 = 0:(1/fs1):(duration-1/fs1);    % Time1
            S1 = amp1 * sin(2*pi*fc1*t1);       % Signal1
        
            ylim(app.UIAxes_Wave1, [-10, 10]);  % Wave1 Y-axis limits
            plot(app.UIAxes_Wave1, t1, S1, 'Color', 'red');     % Wave1 plot
        
            % Modulation -> Wave Traveling Through A Wave

            % Refraction Index and Extinction Coefficient
            refractionIndex = app.RefractionIndexSlider.Value;
            extinctionCoefficient = app.ExtinctioncoefficientSlider.Value;
            threshold_index = round(length(t1) / 2);
            
            % Create using Refraction Index and Extinction Coefficient
            if threshold_index < length(t1)
                % S3(threshold_index:end) = S1(threshold_index:end) .* exp(-refractionIndex .* t1(threshold_index:end)) .* cos(2*pi*extinctionCoefficient*t1(threshold_index:end));
                S3 = S1 .* exp(-refractionIndex .* t1) .* cos(2*pi*extinctionCoefficient*t1);
            end

            % Plot the modulated wave
            plot(app.UIAxes, t1, S3, 'Color', 'blue');
            % Set Y-axis limits
            ylim(app.UIAxes, [-10, 10]);
            
        end

        function resetLabels(app)
            app.AmpLabel.Text = num2str(round(app.AmplitudeSlider.Value));
            app.FreqLabel.Text = num2str(round(app.FrequencySlider.Value));
        end

        function reset(app)
            app.AmplitudeSlider.Value = 5;
            app.FrequencySlider.Value = 4;

            resetLabels(app);
            updateplot(app);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            resetLabels(app);
            updateplot(app)
        end

        % Value changed function: SamplingRateEditField
        function SamplingRateEditFieldValueChanged(app, event)
            updateplot(app);  
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            app.FreqLabel.Text=num2str(round(app.FrequencySlider.Value,2));
            updateplot(app);
        end

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
            app.AmpLabel.Text=num2str(round(app.AmplitudeSlider.Value,2));
            updateplot(app);
        end

        % Button pushed function: ResetWavesButton
        function ResetWavesButtonPushed(app, event)
            reset(app);
        end

        % Value changed function: RefractionIndexSlider
        function RefractionIndexSliderValueChanged(app, event)
            
            updateplot(app);
        end

        % Value changed function: ExtinctioncoefficientSlider
        function ExtinctioncoefficientSliderValueChanged(app, event)
            
            updateplot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Wave1 = uiaxes(app.UIFigure);
            title(app.UIAxes_Wave1, 'Original Wave 1')
            xlabel(app.UIAxes_Wave1, 'Time (sec)')
            ylabel(app.UIAxes_Wave1, 'Amplitude (V)')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wave 1 Through A Medium')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Amplitude (V)')
            zlabel(app.UIAxes, 'Z')
            app.ResetWavesButton = uibutton(app.UIFigure, 'push');
            app.ResetWavesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetWavesButtonPushed, true);
            app.ResetWavesButton.Text = 'Reset Waves';
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.SamplingRateEditFieldLabel = uilabel(app.DisplayPanel);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.SamplingRateEditField.Limits = [1 500];
            app.SamplingRateEditField.RoundFractionalValues = 'on';
            app.SamplingRateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateEditFieldValueChanged, true);
            app.SamplingRateEditField.Value = 100;
            app.DurationsecEditFieldLabel = uilabel(app.DisplayPanel);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.DisplayPanel, 'numeric');
            app.DurationsecEditField.Limits = [1 60];
            app.DurationsecEditField.Value = 2;
            app.WaveMedium = uipanel(app.UIFigure);
            app.WaveMedium.Title = 'Panel';
            app.ExtinctioncoefficientSliderLabel = uilabel(app.WaveMedium);
            app.ExtinctioncoefficientSliderLabel.Text = 'Extinction coefficient';
            app.RefractionIndexSlider = uislider(app.WaveMedium);
            app.RefractionIndexSlider.Limits = [0 2];
            app.RefractionIndexSlider.ValueChangedFcn = createCallbackFcn(app, @RefractionIndexSliderValueChanged, true);
            app.ExtinctioncoefficientSlider = uislider(app.WaveMedium);
            app.ExtinctioncoefficientSlider.Limits = [0 1];
            app.ExtinctioncoefficientSlider.ValueChangedFcn = createCallbackFcn(app, @ExtinctioncoefficientSliderValueChanged, true);
            app.RefractionIndexSliderLabel = uilabel(app.WaveMedium);
            app.RefractionIndexSliderLabel.Text = 'Refraction Index';
            app.Wave1Panel = uipanel(app.UIFigure);
            app.Wave1Panel.Title = 'Wave 1';
            app.AmplitudeSliderLabel = uilabel(app.Wave1Panel);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.Wave1Panel);
            app.AmplitudeSlider.Limits = [0 10];
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.AmplitudeSlider.MinorTicks = [1 3 5 7 9];
            app.AmplitudeSlider.Value = 5;
            app.FrequencySliderLabel = uilabel(app.Wave1Panel);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.Wave1Panel);
            app.FrequencySlider.Limits = [1 9];
            app.FrequencySlider.MajorTicks = [1 3 5 7 9];
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.FrequencySlider.MinorTicks = [2 4 6 8];
            app.FrequencySlider.Value = 4;
            app.AmpLabel = uilabel(app.Wave1Panel);
            app.AmpLabel.Text = 'Amp ';
            app.FreqLabel = uilabel(app.Wave1Panel);
            app.FreqLabel.Text = 'Freq';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = medium

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end